classdef itc_proj_app_pre_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AREADropDownLabel               matlab.ui.control.Label
        AREADropDown                    matlab.ui.control.DropDown
        AQIGaugeLabel                   matlab.ui.control.Label
        AQIGauge                        matlab.ui.control.Gauge
        PM25GaugeLabel                  matlab.ui.control.Label
        PM25Gauge                       matlab.ui.control.Gauge
        PM10GaugeLabel                  matlab.ui.control.Label
        PM10Gauge                       matlab.ui.control.Gauge
        SNoDropDownLabel                matlab.ui.control.Label
        SNoDropDown                     matlab.ui.control.DropDown
        SAFETYMETERGaugeLabel           matlab.ui.control.Label
        SAFETYMETERGauge                matlab.ui.control.LinearGauge
        Image                           matlab.ui.control.Image
        MESSAGELabel                    matlab.ui.control.Label
        AQILabel                        matlab.ui.control.Label
        PM25Label                       matlab.ui.control.Label
        PM10Label                       matlab.ui.control.Label
        REALTIMEAIRQUALITYCHECKERLabel  matlab.ui.control.Label
        gmLabel                         matlab.ui.control.Label
        gmLabel_2                       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SNoDropDown
        function SNoDropDownValueChanged(app, event)
            

           drop_Down_two_val = app.SNoDropDown.Value;
            v = str2double(drop_Down_two_val);   % Selected Option
            
            %Gauge Values
            app.AQIGauge.Value =  AQI(v);
            app.PM25Gauge.Value = PMTPF(v);
            app.PM10Gauge.Value = PMTEN(v);
            app.SAFETYMETERGauge.Value = AQI(v);
            
            %Defining Labels
            app.AQILabel.Text = num2str(AQI(v));
            app.AQILabel.FontWeight = 'bold';
            app.AQILabel.FontSize = 14;
            
            app.PM25Label.Text = num2str(PMTPF(v));
            app.PM25Label.FontWeight = 'bold';        
            app.PM25Label.FontSize = 14;
            
            app.PM10Label.Text = num2str(PMTEN(v));
            app.PM10Label.FontWeight = 'bold';
            app.AQILabel.FontSize = 14;
            
            
            % Displaying the Message based on AQI level
            AqiValue = AQI(v);
            
            if(AqiValue <= 50)
                app.MESSAGELabel.Text = 'AIR QUALITY IN THE SELECTED AREA IS GOOD';
                app.MESSAGELabel.FontWeight = 'bold';
               
            elseif(AqiValue >=51 && AqiValue<=100)
                app.MESSAGELabel.Text = 'AIR QUALITY IN THE SELECTED AREA IS MODERATE';
                app.MESSAGELabel.FontWeight = 'bold';
                
            elseif(AqiValue >=101 && AqiValue<=200)
                app.MESSAGELabel.Text = 'AIR QUALITY IN THE SELECTED AREA IS POOR';
                app.MESSAGELabel.FontWeight = 'bold';
                
            elseif(AqiValue >=201 && AqiValue<=300)
                app.MESSAGELabel.Text = 'AIR QUALITY IN THE SELECTED AREA IS UNHEALTHY';
                app.MESSAGELabel.FontWeight = 'bold';
                
            elseif(AqiValue >=301 && AqiValue<=400)
                app.MESSAGELabel.Text = 'AIR QUALITY IN THE SELECTED AREA IS SEVERE!';
                app.MESSAGELabel.FontWeight = 'bold';
                
            elseif(AqiValue >=401 && AqiValue<=900)
                app.MESSAGELabel.Text = 'AIR QUALITY IN THE SELECTED AREA IS HAZARDOUS!';
                app.MESSAGELabel.FontColor = 'r';
                app.MESSAGELabel.FontWeight = 'bold';
                
            else
                app.MESSAGELabel.Text = 'AIR QUALITY IN THE SELECTED AREA IS DEADLY!';
                app.MESSAGELabel.FontColor = 'r';
                app.MESSAGELabel.FontWeight = 'bold';
                  
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AREADropDownLabel = uilabel(app.UIFigure);
            app.AREADropDownLabel.Text = 'AREA';
            app.AREADropDown = uidropdown(app.UIFigure);
            app.AREADropDown.Items = {'1 Alipur                                     ', '2 Anand Parbat                               ', '3 Ashok Vihar 1 ', '4 Ashok Vihar Phase 1 Block H                             ', '5 Ashok Vihar Phase 4                        ', '6 Bali Nagar                                 ', '7 Bawana Industrial Area                     ', '8 Chanakya Puri                              ', '9 Civil Lines                                ', '10 Darya Ganj                                 ', '11 Delhi Gymkhana Club                        ', '12 Delhi Institute of Tool Engineering        ', '13 Dwarka Sector 10                           ', '14 Dwarka Sector 11                           ', '15 Dwarka Sector 18B                          ', '16 Dwarka Sector 3                            ', '17 Dwarka Sector 5                            ', '18 Dwarka Sector 6                            ', '19 Dwarka Sector 7                            ', '20 East Patel Nagar                           ', '21 Embassy of Belgium                        ', '22 Embassy of the Czech Republic              ', '23 Greater Kailash                            ', '24 Green Park                                 ', '25 Hauz Khas                                  ', '26 I P Extension                              ', '26 ISBT                                       ', '28 ITI Jahangirpuri                           ', '29 Janakpuri                                  ', '30 Jangpura                                   ', '31 Loni                                       ', '32 Model Town                                 ', '33 Mundka                                      ', '34 Narela                                     ', '35 Okhla 2                                    ', '36 Paschim Vihar                              ', '37 Pitam Pura                                ', '38 Pooth Khurd ', '39 Punjabi Bagh', '40 Radhey Mohan Drive', '41 Raghubir Nagar                              ', '42 Rajinder Nagar                             ', '43 Royal Norwegian Embassy 1                  ', '44 Royal Norwegian Embassy 2                  ', '45 Satyawati college                          ', '46 Shaheed Sukhdev College of Business Studies', '47 Shalimar Bagh                              ', '48 Siddhartha Enclave                         ', '49 Sonia Vihar Water Treatment Plant DJB      ', '50 Sukhdev Vihar                              ', '51 Vasant Kunj                              ', '52 Vasant Kunj Sector C '};
            app.AREADropDown.Value = '1 Alipur                                     ';
            app.AQIGaugeLabel = uilabel(app.UIFigure);
            app.AQIGaugeLabel.Text = 'AQI';
            app.AQIGauge = uigauge(app.UIFigure, 'circular');
            app.AQIGauge.Limits = [0 1500];
            app.PM25GaugeLabel = uilabel(app.UIFigure);
            app.PM25GaugeLabel.Text = 'PM 2.5';
            app.PM25Gauge = uigauge(app.UIFigure, 'circular');
            app.PM25Gauge.Limits = [0 1500];
            app.PM10GaugeLabel = uilabel(app.UIFigure);
            app.PM10GaugeLabel.Text = 'PM 10';
            app.PM10Gauge = uigauge(app.UIFigure, 'circular');
            app.PM10Gauge.Limits = [0 1500];
            app.SNoDropDownLabel = uilabel(app.UIFigure);
            app.SNoDropDownLabel.Text = 'S.No';
            app.SNoDropDown = uidropdown(app.UIFigure);
            app.SNoDropDown.Items = {'1', '2 ', '3 ', '4 ', '5 ', '6 ', '7 ', '8 ', '9 ', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52'};
            app.SNoDropDown.ValueChangedFcn = createCallbackFcn(app, @SNoDropDownValueChanged, true);
            app.SNoDropDown.Value = '1';
            app.SAFETYMETERGaugeLabel = uilabel(app.UIFigure);
            app.SAFETYMETERGaugeLabel.Text = 'SAFETY METER';
            app.SAFETYMETERGauge = uigauge(app.UIFigure, 'linear');
            app.SAFETYMETERGauge.Limits = [0 1200];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'cgf222.png';
            app.MESSAGELabel = uilabel(app.UIFigure);
            app.MESSAGELabel.Text = 'MESSAGE: ';
            app.AQILabel = uilabel(app.UIFigure);
            app.AQILabel.Text = 'AQI';
            app.PM25Label = uilabel(app.UIFigure);
            app.PM25Label.Text = 'PM2.5';
            app.PM10Label = uilabel(app.UIFigure);
            app.PM10Label.Text = 'PM10';
            app.REALTIMEAIRQUALITYCHECKERLabel = uilabel(app.UIFigure);
            app.REALTIMEAIRQUALITYCHECKERLabel.Text = 'REAL TIME AIR QUALITY CHECKER';
            app.gmLabel = uilabel(app.UIFigure);
            app.gmLabel.Text = 'μg/m³';
            app.gmLabel_2 = uilabel(app.UIFigure);
            app.gmLabel_2.Text = 'μg/m³';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = itc_proj_app_pre_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end